classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Assigment2BUIFigure         matlab.ui.Figure
        TrainTestButton             matlab.ui.control.Button
        DetectionModeCheckBox       matlab.ui.control.CheckBox
        PatientDropDown             matlab.ui.control.DropDown
        PatientDropDownLabel        matlab.ui.control.Label
        SpecificityPanel            matlab.ui.container.Panel
        Label2                      matlab.ui.control.Label
        SensitivityPanel            matlab.ui.container.Panel
        Label1                      matlab.ui.control.Label
        TestButton                  matlab.ui.control.Button
        NeuralNetworkDropDown       matlab.ui.control.DropDown
        NeuralNetworkDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            
            [sensitivity, specificity] = runNN(app.PatientDropDown.Value, app.NeuralNetworkDropDown.Value, app.DetectionModeCheckBox.Value, 0);
            app.Label1.Text = string(sensitivity);
            app.Label2.Text = string(specificity);
        end

        % Button pushed function: TrainTestButton
        function TrainTestButtonPushed(app, event)
            [sensitivity, specificity] = runNN(app.PatientDropDown.Value, app.NeuralNetworkDropDown.Value, app.DetectionModeCheckBox.Value, 1);
            app.Label1.Text = string(sensitivity);
            app.Label2.Text = string(specificity);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Assigment2BUIFigure = uifigure('Visible', 'off');
            app.Assigment2BUIFigure.Name = 'Assigment 2B';
            app.NeuralNetworkDropDownLabel = uilabel(app.Assigment2BUIFigure);
            app.NeuralNetworkDropDownLabel.Text = 'Neural Network';
            app.NeuralNetworkDropDown = uidropdown(app.Assigment2BUIFigure);
            app.NeuralNetworkDropDown.Items = {'FeedForward', 'DynamicNN', 'CNN', 'LSTM'};
            app.NeuralNetworkDropDown.Value = 'FeedForward';
            app.TestButton = uibutton(app.Assigment2BUIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.SensitivityPanel = uipanel(app.Assigment2BUIFigure);
            app.SensitivityPanel.Title = 'Sensitivity';
            app.Label1 = uilabel(app.SensitivityPanel);
            app.Label1.Text = '0.0';
            app.SpecificityPanel = uipanel(app.Assigment2BUIFigure);
            app.SpecificityPanel.Title = 'Specificity';
            app.Label2 = uilabel(app.SpecificityPanel);
            app.Label2.Text = '0.0';
            app.PatientDropDownLabel = uilabel(app.Assigment2BUIFigure);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.Assigment2BUIFigure);
            app.PatientDropDown.Items = {'44202', '54802', '63502', '112502'};
            app.PatientDropDown.Value = '44202';
            app.DetectionModeCheckBox = uicheckbox(app.Assigment2BUIFigure);
            app.DetectionModeCheckBox.Text = 'Detection Mode';
            app.TrainTestButton = uibutton(app.Assigment2BUIFigure, 'push');
            app.TrainTestButton.ButtonPushedFcn = createCallbackFcn(app, @TrainTestButtonPushed, true);
            app.TrainTestButton.Text = 'Train & Test';
            % Show the figure after all components are created
            app.Assigment2BUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Assigment2BUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Assigment2BUIFigure)
        end
    end
end